classdef SYNTH < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        NoiseIntensitySlider          matlab.ui.control.Slider
        NoiseIntensitySliderLabel     matlab.ui.control.Label
        OscillatorFrequencyEditField  matlab.ui.control.EditField
        EnterFrequencyForOscillatorEditFieldLabel  matlab.ui.control.Label
        SawtoothAmpSlider             matlab.ui.control.Slider
        AmplitudeSlider_3Label        matlab.ui.control.Label
        SquareOscWidthSlider          matlab.ui.control.Slider
        WidthSliderLabel              matlab.ui.control.Label
        SquareOscAmpSlider            matlab.ui.control.Slider
        AmplitudeSliderLabel          matlab.ui.control.Label
        SawtoothOscAxes               matlab.ui.control.UIAxes
        SquareOscAxes                 matlab.ui.control.UIAxes
        CenterPanel                   matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        VolumeSlider                  matlab.ui.control.Slider
        VolumeSliderLabel             matlab.ui.control.Label
        FileNameEditField             matlab.ui.control.EditField
        ImportFileButton              matlab.ui.control.Button
        MediaControlLabel             matlab.ui.control.Label
        Button                        matlab.ui.control.Button
        Button_2                      matlab.ui.control.Button
        TimeAxes                      matlab.ui.control.UIAxes
        FreqAxes                      matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        GridLayout1                   matlab.ui.container.GridLayout
        LFOFreqSliderLabel_2          matlab.ui.control.Label
        LFOFreqSlider_2               matlab.ui.control.Slider
        LFOFreqSliderLabel            matlab.ui.control.Label
        LFOFreqSlider                 matlab.ui.control.Slider
        LFOFormButtonGroup            matlab.ui.container.ButtonGroup
        SqButton                      matlab.ui.control.ToggleButton
        SawButton                     matlab.ui.control.ToggleButton
        SinButton                     matlab.ui.control.ToggleButton
        AttackSlider                  matlab.ui.control.Slider
        DecaySlider                   matlab.ui.control.Slider
        DecaysLabel                   matlab.ui.control.Label
        AttacksLabel                  matlab.ui.control.Label
        SustainSlider                 matlab.ui.control.Slider
        ReleasesSlider                matlab.ui.control.Slider
        ReleasesSliderLabel           matlab.ui.control.Label
        SustainSliderLabel            matlab.ui.control.Label
        ADSREnvelopeAxesAmplitude     matlab.ui.control.UIAxes
        LFOAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % instance vars:
    % originalWaveFileObj - object holding all information in original wav
    % currWaveData - object holding all information in new wave file
    % squareOscillator - time domain array storing square oscillator
    % sawtoothOscillator - time domain array storing sawtooth oscillator
    % 
    % duration - duration of the file (sec)
    % fs - sampling frequency of the file
    % waveAmplitude - total amplitude of the wave 
    % 
    % oscillatorFrequency - controls frequnecy of the oscillators
    % squareAmplitude - amplitude of square oscillator
    % squareWidth - parameter to control duty cycle of square oscillator
    % sawtoothAmplitude - amplitude of the sawtooth wave
    %
    %   adsrEnvelopeAmplitude - 1d array containing envelope data
    %   adsrAttackAmplitude - length of attack of amplitude (sec)
    %   adsrDecayAmplitude - length of decay of amplitude (sec)
    %   adsrSustainAmplitude - sustain percent (%)
    %   adsrReleaseAmplitude - length of release of amplitude (sec)
    %
    %   LFOFreq - frequency
    %   LFO - 1d array containing LFO data
    %   LFOType - type of wave
    %   LFOAmplitude - amplitude of LFO
    %
    %   noiseIntensity - intensity of noise [1-10]
    properties (Access = private)

        originalWaveFileObj
        currWaveFileObj
        squareOscillator
        sawtoothOscillator

        duration
        fs
        waveAmplitude

        oscillatorFrequency
        squareAmplitude
        squareWidth
        sawtoothAmplitude

        adsrEnvelopeAmplitude
        adsrAttackAmplitude
        adsrDecayAmplitude
        adsrSustainAmplitude
        adsrReleaseAmplitude

        LFOFreq
        LFO
        LFOType
        LFOAmplitude

        noiseIntensity
    end
    
    % All helper methods to handle gui interaction
    methods (Access = private)

        %{
            Initializes the .wav file object to be used

            @param filePath - the path of the file read in
        %}
        function loadWav(app, filePath)
        
            app.originalWaveFileObj = WaveFile(filePath);
            app.currWaveFileObj = app.originalWaveFileObj;
        end
        
        %{
            Initializes the square oscillator
        %}
        function initializeSquare(app, amplitude, freq, width, duration, fs)

            app.squareOscillator = generateSquareOscillator(amplitude, freq, width, duration, fs);
            % set range for [0, 2]
            app.squareOscillator = app.squareOscillator + 1;
        end

        %{
            Initializes the square oscillator
        %}
        function initializeSawtooth(app, amplitude, freq,  duration, fs)
            
            app.sawtoothOscillator = generateSawtoothOscillator(amplitude, freq, duration, fs);
            % set range for [0, 2]
            app.sawtoothOscillator = app.sawtoothOscillator + 1;
        end

        %{
            Initializes the ADSR envelope for amplitude
        %}
        function initializeADSRAmplitude(app, duration, attack, decay, sustain, release, fs)

            app.adsrEnvelopeAmplitude = generateEnvelope(duration, attack, decay, sustain, release, fs);
        end

        %{
            Initializes LFO
        %}
        function initializeLFO(app)
            
            app.LFO = generateLFO(app.LFOType, app.LFOFreq, ...
                app.duration, app.fs);
            
            app.LFO = (app.LFOAmplitude * app.LFO)+1;
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        %{
            Plays sound of .wav file, from the beginning
        %}
        function playAudio(app)
         
            playSound(app.currWaveFileObj);    
        end

        %{
            Stops playing sound (if currently playing)
        %}
        function stopAudio(app)

            stopSound();   
        end

        %{
            Applies all calculations (in order)
        %}
        function updateSample(app)

            modulateWithOscillators(app);
            applyADSR(app);
            applyLFO(app);
            applyNoise(app);
            app.currWaveFileObj = changeAmplitude(app.currWaveFileObj, app.waveAmplitude/50);
        end

        %{
            Modulates wave with both oscillators
        %}
        function modulateWithOscillators(app)
            
            app.currWaveFileObj.timeData = modulation(app.originalWaveFileObj.timeData, app.squareOscillator);
            app.currWaveFileObj.timeData = modulation(app.currWaveFileObj.timeData, app.sawtoothOscillator);
        end

        %{
            Applies ADSR to amplitude
        %}
        function applyADSR(app)

            app.currWaveFileObj.timeData = app.currWaveFileObj.timeData .* app.adsrEnvelopeAmplitude;
        end

        %{
            Applies LFO to amplitude
        %}
        function applyLFO(app)

            app.currWaveFileObj.timeData = modulation(app.currWaveFileObj.timeData, app.LFO);
        end

        %{
            Applies noise to sample
        %}
        function applyNoise(app)
            disp(size(generateNoise(app.noiseIntensity, app.duration, app.fs)))
            disp(size(app.currWaveFileObj.timeData));
            app.currWaveFileObj.timeData = app.currWaveFileObj.timeData + generateNoise(app.noiseIntensity, app.duration, app.fs).';
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %{
            Plots the two graphs of our data.
        %}
        function loadPlot(app)

            plot(app.TimeAxes, app.currWaveFileObj.timeData);
            ylim(app.TimeAxes, [-1, 1]);
            plot(app.FreqAxes, abs(fft(app.currWaveFileObj.timeData)));
        end

        %{
            Plots oscillator type. Currently only plotting 2 periods

            @param axesHandle - axes reference
            @param audioData - data of audio (time domain)
        %}
        function plotOscillator(app, axesHandle, fs, freq, audioData)
            
            numPeriodsToPlot = 2;
            plot(axesHandle, audioData(1:fs/freq * numPeriodsToPlot)); 
            
            ylim(axesHandle, [-1, 3]);
            yline(axesHandle, 1, 'k--');
        end

        %{
            Modulates wave with both oscillators
        %}
        function plotADSR(app, axesHandle, envelope)

            plot(axesHandle, envelope); 
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath(pwd));
            
            app.oscillatorFrequency = 440;
            app.duration = 2;
            app.fs = 44100;
            app.waveAmplitude = 50;

            app.squareWidth = 0;
            app.squareAmplitude = 1;
            app.sawtoothAmplitude = 1;

            initializeSquare(app, app.squareAmplitude, ...
                app.oscillatorFrequency, app.squareWidth, app.duration, app.fs);

            initializeSawtooth(app, app.sawtoothAmplitude/2, ...
                app.oscillatorFrequency, app.duration, app.fs);

            app.adsrAttackAmplitude = 0.1;
            app.adsrDecayAmplitude = 0.5;
            app.adsrSustainAmplitude = 50;
            app.adsrReleaseAmplitude = 0.15;
            
            app.LFOFreq = 10;
            app.LFOAmplitude = 1;

            initializeADSRAmplitude(app, app.duration, app.adsrAttackAmplitude, ...
                app.adsrDecayAmplitude,app.adsrSustainAmplitude, app.adsrReleaseAmplitude, app.fs);
            
            app.LFOType = 'sin_wave';
            initializeLFO(app);

            app.noiseIntensity = 0;
            plotOscillator(app, app.SquareOscAxes, app.fs, app.oscillatorFrequency, app.squareOscillator);
            plotOscillator(app, app.SawtoothOscAxes, app.fs, app.oscillatorFrequency, app.sawtoothOscillator);
            plotADSR(app, app.ADSREnvelopeAxesAmplitude, app.adsrEnvelopeAmplitude);
            plotOscillator(app, app.LFOAxes, app.fs, app.LFOFreq, app.LFO);
        end

        % Button pushed function: ImportFileButton
        function ImportFileButtonPushed2(app, event)
            % read in data. ref:
            % https://www.mathworks.com/help/matlab/ref/uigetfile.html
            % https://www.mathworks.com/help/matlab/ref/fullfile.html
            
            [filename, path] = uigetfile('*.wav');
            app.FileNameEditField.Value = filename;
            
            loadWav(app, fullfile(path, filename));
            app.fs = app.originalWaveFileObj.fs;
            app.duration = length(app.originalWaveFileObj.timeData)/app.fs;


            initializeSquare(app, app.squareAmplitude, app.oscillatorFrequency, app.squareWidth, app.duration, app.fs); 
            initializeSawtooth(app, app.sawtoothAmplitude/2, app.oscillatorFrequency, app.duration, app.fs);
            

            initializeADSRAmplitude(app, app.duration, app.adsrAttackAmplitude, ...
                app.adsrDecayAmplitude,app.adsrSustainAmplitude, app.adsrReleaseAmplitude, app.fs);

            initializeLFO(app);
            
            updateSample(app);
            % load the plots whenever new file is uploaded
            loadPlot(app);
            plotOscillator(app, app.SquareOscAxes, app.currWaveFileObj.fs, app.oscillatorFrequency, app.squareOscillator);
            plotOscillator(app, app.SawtoothOscAxes, app.currWaveFileObj.fs, app.oscillatorFrequency, app.sawtoothOscillator);
            plotADSR(app, app.ADSREnvelopeAxesAmplitude, app.adsrEnvelopeAmplitude);
            plotOscillator(app, app.LFOAxes, app.fs, app.LFOFreq, app.LFO);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            playAudio(app);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            stopAudio(app);
        end

        % Value changed function: VolumeSlider
        function VolumeSliderValueChanged2(app, event)
            app.waveAmplitude = app.VolumeSlider.Value;

            updateSample(app);
            loadPlot(app);
        end

        % Value changed function: SquareOscAmpSlider
        function SquareOscAmpSliderValueChanged(app, event)
            app.squareAmplitude = app.SquareOscAmpSlider.Value / 50;

            initializeSquare(app, app.squareAmplitude, app.oscillatorFrequency, app.squareWidth, app.duration, app.fs);
            
            updateSample(app);
            plotOscillator(app, app.SquareOscAxes, app.fs, app.oscillatorFrequency, app.squareOscillator);
            loadPlot(app);
        end

        % Value changed function: SquareOscWidthSlider
        function SquareOscWidthSliderValueChanged(app, event)
            app.squareWidth = app.SquareOscWidthSlider.Value;

            initializeSquare(app, app.squareAmplitude, app.oscillatorFrequency, app.squareWidth, app.duration, app.fs);
            
            updateSample(app);
            plotOscillator(app, app.SquareOscAxes, app.currWaveFileObj.fs, app.oscillatorFrequency, app.squareOscillator);
            loadPlot(app);
        end

        % Value changed function: SawtoothAmpSlider
        function SawtoothAmpSliderValueChanged(app, event)
            app.sawtoothAmplitude = app.SawtoothAmpSlider.Value/(2 * 50);
            
            initializeSawtooth(app, app.sawtoothAmplitude, app.oscillatorFrequency, app.duration, app.fs); 
            
            updateSample(app);
            plotOscillator(app, app.SawtoothOscAxes, app.fs, app.oscillatorFrequency, app.sawtoothOscillator);
            loadPlot(app);
        end

        % Value changed function: OscillatorFrequencyEditField
        function OscillatorFrequencyEditFieldValueChanged(app, event)
            app.oscillatorFrequency = str2double(app.OscillatorFrequencyEditField.Value);

            initializeSquare(app, app.squareAmplitude, app.oscillatorFrequency, app.squareWidth, app.duration, app.fs); 
            initializeSawtooth(app, app.sawtoothAmplitude/2, app.oscillatorFrequency, app.duration, app.fs);
            
            updateSample(app);
            plotOscillator(app, app.SquareOscAxes, app.currWaveFileObj.fs, app.oscillatorFrequency, app.squareOscillator);
            loadPlot(app);
        end

        % Value changed function: AttackSlider
        function AttackSliderValueChanged(app, event)
            app.adsrAttackAmplitude = app.AttackSlider.Value;

            initializeADSRAmplitude(app, app.duration, app.adsrAttackAmplitude, ...
                app.adsrDecayAmplitude,app.adsrSustainAmplitude, app.adsrReleaseAmplitude, app.fs);

            updateSample(app);
            loadPlot(app);
            plotADSR(app, app.ADSREnvelopeAxesAmplitude, app.adsrEnvelopeAmplitude);
        end

        % Value changed function: DecaySlider
        function DecaySliderValueChanged(app, event)
            app.adsrDecayAmplitude = app.DecaySlider.Value;
            
            initializeADSRAmplitude(app, app.duration, app.adsrAttackAmplitude, ...
                app.adsrDecayAmplitude,app.adsrSustainAmplitude, app.adsrReleaseAmplitude, app.fs);

            updateSample(app);
            loadPlot(app);
            plotADSR(app, app.ADSREnvelopeAxesAmplitude, app.adsrEnvelopeAmplitude);
        end

        % Value changed function: SustainSlider
        function SustainSliderValueChanged(app, event)
            app.adsrSustainAmplitude = app.SustainSlider.Value;
            
            initializeADSRAmplitude(app, app.duration, app.adsrAttackAmplitude, ...
                app.adsrDecayAmplitude,app.adsrSustainAmplitude, app.adsrReleaseAmplitude, app.fs);

            updateSample(app);
            loadPlot(app);
            plotADSR(app, app.ADSREnvelopeAxesAmplitude, app.adsrEnvelopeAmplitude);
        end

        % Value changed function: ReleasesSlider
        function ReleasesSliderValueChanged(app, event)
            app.adsrReleaseAmplitude = app.ReleasesSlider.Value;

            initializeADSRAmplitude(app, app.duration, app.adsrAttackAmplitude, ...
                app.adsrDecayAmplitude,app.adsrSustainAmplitude, app.adsrReleaseAmplitude, app.fs);

            updateSample(app);
            loadPlot(app);
            plotADSR(app, app.ADSREnvelopeAxesAmplitude, app.adsrEnvelopeAmplitude);
        end

        % Selection changed function: LFOFormButtonGroup
        function LFOFormButtonGroupSelectionChanged(app, event)
            selectedButton = app.LFOFormButtonGroup.SelectedObject;
            if selectedButton == app.SinButton
                app.LFOType = 'sin_wave';
                initializeLFO(app);
            end

            if selectedButton == app.SawButton
                app.LFOType = 'sawtooth_wave';
                initializeLFO(app);
            end
            
            if selectedButton == app.SqButton
                app.LFOType = 'square_wave';
                initializeLFO(app);
            end
            
            updateSample(app);
            loadPlot(app);
            plotOscillator(app, app.LFOAxes, app.fs, app.LFOFreq, app.LFO);
        end

        % Value changed function: LFOFreqSlider
        function LFOFreqSliderValueChanged(app, event)
            app.LFOFreq = round(app.LFOFreqSlider.Value);
            
            initializeLFO(app);
            updateSample(app);
            loadPlot(app);
            plotOscillator(app, app.LFOAxes, app.fs, app.LFOFreq, app.LFO);
        end

        % Value changed function: LFOFreqSlider_2
        function LFOFreqSlider_2ValueChanged(app, event)
            app.LFOAmplitude = app.LFOFreqSlider_2.Value/50;

            initializeLFO(app);
            updateSample(app);
            loadPlot(app);
            plotOscillator(app, app.LFOAxes, app.fs, app.LFOFreq, app.LFO);
        end

        % Value changed function: NoiseIntensitySlider
        function NoiseIntensitySliderValueChanged(app, event)
            app.noiseIntensity = app.NoiseIntensitySlider.Value;
            updateSample(app);
            loadPlot(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {559, 559, 559};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {559, 559};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {258, '1x', 277};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.SquareOscAxes = uiaxes(app.GridLayout2);
            title(app.SquareOscAxes, 'Square Oscillator (2 Periods)')
            app.SquareOscAxes.XTick = [];
            app.SquareOscAxes.YTick = [];
            app.SawtoothOscAxes = uiaxes(app.GridLayout2);
            title(app.SawtoothOscAxes, 'Sawtooth Oscillator (2 Periods)')
            app.SawtoothOscAxes.XTick = [];
            app.SawtoothOscAxes.YTick = [];
            app.AmplitudeSliderLabel = uilabel(app.GridLayout2);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.SquareOscAmpSlider = uislider(app.GridLayout2);
            app.SquareOscAmpSlider.ValueChangedFcn = createCallbackFcn(app, @SquareOscAmpSliderValueChanged, true);
            app.SquareOscAmpSlider.Value = 50;
            app.WidthSliderLabel = uilabel(app.GridLayout2);
            app.WidthSliderLabel.Text = 'Width';
            app.SquareOscWidthSlider = uislider(app.GridLayout2);
            app.SquareOscWidthSlider.Limits = [-100 100];
            app.SquareOscWidthSlider.MajorTicks = [-100 -50 0 50 100];
            app.SquareOscWidthSlider.ValueChangedFcn = createCallbackFcn(app, @SquareOscWidthSliderValueChanged, true);
            app.SquareOscWidthSlider.MinorTicks = [-100 -75 -50 -25 0 25 50 75 100];
            app.AmplitudeSlider_3Label = uilabel(app.GridLayout2);
            app.AmplitudeSlider_3Label.Text = 'Amplitude';
            app.SawtoothAmpSlider = uislider(app.GridLayout2);
            app.SawtoothAmpSlider.ValueChangedFcn = createCallbackFcn(app, @SawtoothAmpSliderValueChanged, true);
            app.SawtoothAmpSlider.Value = 50;
            app.EnterFrequencyForOscillatorEditFieldLabel = uilabel(app.GridLayout2);
            app.EnterFrequencyForOscillatorEditFieldLabel.Text = 'Enter Frequency For Oscillator';
            app.OscillatorFrequencyEditField = uieditfield(app.GridLayout2, 'text');
            app.OscillatorFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @OscillatorFrequencyEditFieldValueChanged, true);
            app.OscillatorFrequencyEditField.Value = '440';
            app.NoiseIntensitySliderLabel = uilabel(app.GridLayout2);
            app.NoiseIntensitySliderLabel.Text = 'Noise Intensity';
            app.NoiseIntensitySlider = uislider(app.GridLayout2);
            app.NoiseIntensitySlider.Limits = [0 10];
            app.NoiseIntensitySlider.ValueChangedFcn = createCallbackFcn(app, @NoiseIntensitySliderValueChanged, true);
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.CenterPanel);
            app.FreqAxes = uiaxes(app.GridLayout3);
            title(app.FreqAxes, 'Frequency Waveform')
            xlabel(app.FreqAxes, 'Angular Frequency')
            ylabel(app.FreqAxes, 'Amplitude')
            zlabel(app.FreqAxes, 'Z')
            app.FreqAxes.XTick = [];
            app.FreqAxes.YTick = [];
            app.TimeAxes = uiaxes(app.GridLayout3);
            title(app.TimeAxes, 'Audio Waveform')
            xlabel(app.TimeAxes, 'Time')
            ylabel(app.TimeAxes, 'Amplitude')
            zlabel(app.TimeAxes, 'Z')
            app.TimeAxes.XTick = [];
            app.TimeAxes.YTick = [];
            app.Button_2 = uibutton(app.GridLayout3, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '■';
            app.Button = uibutton(app.GridLayout3, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '►';
            app.MediaControlLabel = uilabel(app.GridLayout3);
            app.MediaControlLabel.Text = 'Media Control';
            app.ImportFileButton = uibutton(app.GridLayout3, 'push');
            app.ImportFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportFileButtonPushed2, true);
            app.ImportFileButton.Text = 'Import File';
            app.FileNameEditField = uieditfield(app.GridLayout3, 'text');
            app.FileNameEditField.Value = 'File Name';
            app.VolumeSliderLabel = uilabel(app.GridLayout3);
            app.VolumeSliderLabel.Text = 'Volume';
            app.VolumeSlider = uislider(app.GridLayout3);
            app.VolumeSlider.ValueChangedFcn = createCallbackFcn(app, @VolumeSliderValueChanged2, true);
            app.VolumeSlider.Value = 50;
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout1 = uigridlayout(app.RightPanel);
            app.LFOAxes = uiaxes(app.GridLayout1);
            title(app.LFOAxes, 'Oscillator Waveform (2 Periods)')
            app.LFOAxes.XTick = [];
            app.LFOAxes.YTick = [];
            app.ADSREnvelopeAxesAmplitude = uiaxes(app.GridLayout1);
            title(app.ADSREnvelopeAxesAmplitude, 'Amplitude Envelope')
            app.ADSREnvelopeAxesAmplitude.XTick = [];
            app.ADSREnvelopeAxesAmplitude.YTick = [];
            app.SustainSliderLabel = uilabel(app.GridLayout1);
            app.SustainSliderLabel.Text = 'Sustain (%)';
            app.ReleasesSliderLabel = uilabel(app.GridLayout1);
            app.ReleasesSliderLabel.Text = 'Release (s)';
            app.ReleasesSlider = uislider(app.GridLayout1);
            app.ReleasesSlider.Limits = [0 5];
            app.ReleasesSlider.ValueChangedFcn = createCallbackFcn(app, @ReleasesSliderValueChanged, true);
            app.ReleasesSlider.MinorTicks = [0.5 1.5 2.5 3.5 4.5];
            app.ReleasesSlider.Value = 1.5;
            app.SustainSlider = uislider(app.GridLayout1);
            app.SustainSlider.ValueChangedFcn = createCallbackFcn(app, @SustainSliderValueChanged, true);
            app.SustainSlider.MinorTicks = [10 30 50 70 90];
            app.SustainSlider.Value = 50;
            app.AttacksLabel = uilabel(app.GridLayout1);
            app.AttacksLabel.Text = 'Attack (s)';
            app.DecaysLabel = uilabel(app.GridLayout1);
            app.DecaysLabel.Text = 'Decay (s)';
            app.DecaySlider = uislider(app.GridLayout1);
            app.DecaySlider.Limits = [0 5];
            app.DecaySlider.MajorTicks = [0 1 2 3 4 5];
            app.DecaySlider.ValueChangedFcn = createCallbackFcn(app, @DecaySliderValueChanged, true);
            app.DecaySlider.MinorTicks = [0.5 1.5 2.5 3.5 4.5];
            app.DecaySlider.Value = 0.5;
            app.AttackSlider = uislider(app.GridLayout1);
            app.AttackSlider.Limits = [0 5];
            app.AttackSlider.MajorTicks = [0 1 2 3 4 5];
            app.AttackSlider.ValueChangedFcn = createCallbackFcn(app, @AttackSliderValueChanged, true);
            app.AttackSlider.MinorTicks = [0.5 1.5 2.5 3.5 4.5];
            app.AttackSlider.Value = 0.1;
            app.LFOFormButtonGroup = uibuttongroup(app.GridLayout1);
            app.LFOFormButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LFOFormButtonGroupSelectionChanged, true);
            app.LFOFormButtonGroup.Title = 'LFO Form';
            app.SinButton = uitogglebutton(app.LFOFormButtonGroup);
            app.SinButton.Text = 'Sin';
            app.SinButton.Value = true;
            app.SawButton = uitogglebutton(app.LFOFormButtonGroup);
            app.SawButton.Text = 'Saw';
            app.SqButton = uitogglebutton(app.LFOFormButtonGroup);
            app.SqButton.Text = 'Sq.';
            app.LFOFreqSlider = uislider(app.GridLayout1);
            app.LFOFreqSlider.Limits = [0 20];
            app.LFOFreqSlider.MajorTicks = [0 5 10 15 20];
            app.LFOFreqSlider.ValueChangedFcn = createCallbackFcn(app, @LFOFreqSliderValueChanged, true);
            app.LFOFreqSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.LFOFreqSlider.Value = 10;
            app.LFOFreqSliderLabel = uilabel(app.GridLayout1);
            app.LFOFreqSliderLabel.Text = 'Frequency (Hz)';
            app.LFOFreqSlider_2 = uislider(app.GridLayout1);
            app.LFOFreqSlider_2.MajorTicks = [0 20 40 60 80 100];
            app.LFOFreqSlider_2.ValueChangedFcn = createCallbackFcn(app, @LFOFreqSlider_2ValueChanged, true);
            app.LFOFreqSlider_2.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.LFOFreqSlider_2.Value = 50;
            app.LFOFreqSliderLabel_2 = uilabel(app.GridLayout1);
            app.LFOFreqSliderLabel_2.Text = 'Amplitude';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SYNTH

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end